classdef defog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        ChooseImageButton     matlab.ui.control.Button
        SaveImageButton       matlab.ui.control.Button
        DefogButton           matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        ChooseAlgorithmLabel  matlab.ui.control.Label
        ImageDefogLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        im;    %original image
        dehaze;%dehaze image
        value= 'DarkChannel';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'Choose a Image', '/Users');
            str=[pathname filename];
            if  isequal(filename,0)||isequal(pathname,0)
                warndlg('Please select a picture first!','Warning');
                return;
            else
              app.im = imread(str); 
              imshow(app.im,'parent',app.UIAxes);
            end
        end

        % Button pushed function: DefogButton
        function DefogButtonPushed(app, event)
            if strcmp(app.value,'DarkChannel')
                app.dehaze = darkChannel(app.im);
                imshow(app.dehaze,'parent',app.UIAxes_2);
                return;
            elseif strcmp(app.value,'DazeNet')
                haze=double(app.im)./255;
                app.dehaze=run_cnn(haze);
                imshow(app.dehaze,'parent',app.UIAxes_2);
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            [FileName,PathName] = uiputfile({'*.jpg','JPEG(*.jpg)';...
                                 '*.bmp','Bitmap(*.bmp)';...
                                 '*.gif','GIF(*.gif)';...
                                 '*.*',  'All Files (*.*)'},...
                                 'Save Picture','Untitled');
             if FileName==0
                 return;
             else
                 imwrite(app.dehaze,[PathName,FileName]);
             end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
             app.value = app.DropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.DefogButton = uibutton(app.UIFigure, 'push');
            app.DefogButton.ButtonPushedFcn = createCallbackFcn(app, @DefogButtonPushed, true);
            app.DefogButton.Text = 'Defog';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'DarkChannel', 'DazeNet'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'DarkChannel';
            app.ChooseAlgorithmLabel = uilabel(app.UIFigure);
            app.ChooseAlgorithmLabel.Text = 'Choose  Algorithm ';
            app.ImageDefogLabel = uilabel(app.UIFigure);
            app.ImageDefogLabel.Text = 'Image Defog';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end